classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PointBZEditField              matlab.ui.control.NumericEditField
        PointBZEditFieldLabel         matlab.ui.control.Label
        PointBYEditField              matlab.ui.control.NumericEditField
        PointBYEditFieldLabel         matlab.ui.control.Label
        PointBXEditField              matlab.ui.control.NumericEditField
        PointBXEditFieldLabel         matlab.ui.control.Label
        PointAZEditField              matlab.ui.control.NumericEditField
        PointAZEditFieldLabel         matlab.ui.control.Label
        PointAYEditField              matlab.ui.control.NumericEditField
        PointAYEditFieldLabel         matlab.ui.control.Label
        PointAXEditField              matlab.ui.control.NumericEditField
        PointAXEditFieldLabel         matlab.ui.control.Label
        GenerateCoordinatesButton     matlab.ui.control.Button
        NumberofCheckpointsEditField  matlab.ui.control.NumericEditField
        NumberofCheckpointsEditFieldLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        InitializeButton              matlab.ui.control.Button
        LetsExploreButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        WaypointMatrix % Description
    end
    
    methods (Access = private)
        
        function createMFileFromMat(matFileName, mFileName)
    % Load the .mat file
            data = load(matFileName);
            
            % Open the .m file for writing
            fileID = fopen(mFileName, 'w');
            
            % Iterate over each variable in the loaded data
            vars = fieldnames(data);
            for i = 1:length(vars)
                varName = vars{i};
                varValue = data.(varName);
                
                % Handle different types of data differently (e.g., numeric, string)
                if isnumeric(varValue) || islogical(varValue)
                    % Convert the numeric array to a string representation
                    strValue = mat2str(varValue);
                    fprintf(fileID, '%s = %s;\n', varName, strValue);
                elseif ischar(varValue) || isstring(varValue)
                    % Handle strings (single quotes are needed in the .m file)
                    fprintf(fileID, '%s = ''%s'';\n', varName, varValue);
                else
                    warning('Variable type for %s not handled in this example.', varName);
                end
            end
            
            % Close the .m file
            fclose(fileID);
            disp(['Data has been written to ', mFileName]);

            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LetsExploreButton
        function LetsExploreButtonPushed(app, event)
           % Retrieve the number of waypoints and the waypoint data from the app's UI
    WPNum = app.NumberofCheckpointsEditField.Value;
    waypointData = app.UITable.Data;
    
    % Check for empty waypoint data
    if isempty(waypointData)
        uialert(app.UIFigure, 'Waypoint data is empty. Cannot start simulation.', 'Error');
        return;
    end
    
    % Assign WPNum and waypoint data to the base workspace
    assignin('base', 'WPNum', WPNum);
    assignin('base', 'Waypoints', waypointData);

    
    % Load and simulate the model
    modelName = 'Coupled3DPathFollowing';
    load_system(modelName);
    simOut = sim(modelName);
    
    % % After the simulation, check if 'logsout' exists
    % if evalin('base', 'exist(''logsout'', ''var'')')
    %     % Fetch logsout from the base workspace
    %     logsout = evalin('base', 'logsout');
    % 
    %     % Call the plotting function with logsout as an argument
    %     plotCoupled3DTrack(logsout);
    % else
    %     error('logsout is undefined. Make sure your simulation is configured to log data.');
    % end
        end

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
             numberOfRows = app.NumberofCheckpointsEditField.Value;
    
                    % Create an n x 3 matrix filled with zeros (or default values)
               matrixData = zeros(numberOfRows, 3);
                    
                    % Update the table with the new matrix
               app.UITable.Data = matrixData;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

        end

        % Button pushed function: GenerateCoordinatesButton
        function GenerateCoordinatesButtonPushed(app, event)
             % Retrieve start and end points from the UI
    pointA = [app.PointAXEditField.Value, app.PointAYEditField.Value, app.PointAZEditField.Value];
    pointB = [app.PointBXEditField.Value, app.PointBYEditField.Value, app.PointBZEditField.Value];
    
    % Retrieve the number of waypoints from the UI
    WPNum = app.NumberofCheckpointsEditField.Value;
    
    % Initialize and generate waypoints using linear interpolation
    Waypoints = zeros(WPNum, 3);
    for i = 1:WPNum
        t = (i-1) / (WPNum-1);
        Waypoints(i, :) = pointA + t * (pointB - pointA);
    end
    
    % Update the UITable with generated waypoints
    app.UITable.Data = Waypoints;
    
    % Optionally, save to a .mat file if needed
    save('Waypoints.mat', 'Waypoints');
    %createMFileFromMat('Waypoints.mat', 'SetWaypoints.m');
    %matFileName = 'Waypoints.mat'; % Update with your actual file name
    %mFileName = 'SetWaypoint.m'; % Desired output .m file name
    %createMFileFromMat(Waypoints.mat, SetWaypoints.m);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LetsExploreButton = uibutton(app.UIFigure, 'push');
            app.LetsExploreButton.ButtonPushedFcn = createCallbackFcn(app, @LetsExploreButtonPushed, true);
            app.LetsExploreButton.Text = 'Let''s Explore';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.NumberofCheckpointsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofCheckpointsEditFieldLabel.Text = 'Number of Checkpoints';
            app.NumberofCheckpointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofCheckpointsEditField.Value = 2;
            app.GenerateCoordinatesButton = uibutton(app.UIFigure, 'push');
            app.GenerateCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCoordinatesButtonPushed, true);
            app.GenerateCoordinatesButton.Text = 'Generate Coordinates';
            app.PointAXEditFieldLabel = uilabel(app.UIFigure);
            app.PointAXEditFieldLabel.Text = 'Point A (X)';
            app.PointAXEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointAYEditFieldLabel = uilabel(app.UIFigure);
            app.PointAYEditFieldLabel.Text = 'Point A (Y)';
            app.PointAYEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointAZEditFieldLabel = uilabel(app.UIFigure);
            app.PointAZEditFieldLabel.Text = 'Point A (Z)';
            app.PointAZEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointBXEditFieldLabel = uilabel(app.UIFigure);
            app.PointBXEditFieldLabel.Text = 'Point B (X)';
            app.PointBXEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointBYEditFieldLabel = uilabel(app.UIFigure);
            app.PointBYEditFieldLabel.Text = 'Point B (Y)';
            app.PointBYEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointBZEditFieldLabel = uilabel(app.UIFigure);
            app.PointBZEditFieldLabel.Text = 'Point B (Z)';
            app.PointBZEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end